classdef bright_lesion_detect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BrightLesionDetectionToolUIFigure  matlab.ui.Figure
        extractgreenchannelLabel  matlab.ui.control.Label
        removebackgroundLabel     matlab.ui.control.Label
        lesioncandidateLabel      matlab.ui.control.Label
        applykirschkernelLabel    matlab.ui.control.Label
        removevesselLabel         matlab.ui.control.Label
        reconstructLabel          matlab.ui.control.Label
        lesionedgecandidateLabel  matlab.ui.control.Label
        resultLabel               matlab.ui.control.Label
        extractvesselLabel        matlab.ui.control.Label
        ChooseimageButton         matlab.ui.control.Button
        origin                    matlab.ui.control.UIAxes
        removeBg                  matlab.ui.control.UIAxes
        lesionCan                 matlab.ui.control.UIAxes
        recon                     matlab.ui.control.UIAxes
        result                    matlab.ui.control.UIAxes
        greenChan                 matlab.ui.control.UIAxes
        kirsch                    matlab.ui.control.UIAxes
        removeVes                 matlab.ui.control.UIAxes
        lesionEdge                matlab.ui.control.UIAxes
        extractVes                matlab.ui.control.UIAxes
        status                    matlab.ui.control.Label
        imageName                 matlab.ui.control.Label
        VLabel                    matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        Label_4                   matlab.ui.control.Label
        Label_5                   matlab.ui.control.Label
        Label_6                   matlab.ui.control.Label
        Label_7                   matlab.ui.control.Label
        Label_8                   matlab.ui.control.Label
        Label_9                   matlab.ui.control.Label
        Label_10                  matlab.ui.control.Label
        Label_11                  matlab.ui.control.Label
        Label_12                  matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.origin.XAxis.TickLabels = {};
            app.origin.YAxis.TickLabels = {};
            
            app.removeBg.XAxis.TickLabels = {};
            app.removeBg.YAxis.TickLabels = {};
            
            app.lesionCan.XAxis.TickLabels = {};
            app.lesionCan.YAxis.TickLabels = {};
            
            app.recon.XAxis.TickLabels = {};
            app.recon.YAxis.TickLabels = {};
            
            app.result.XAxis.TickLabels = {};
            app.result.YAxis.TickLabels = {};
            
            app.greenChan.XAxis.TickLabels = {};
            app.greenChan.YAxis.TickLabels = {};
            
            app.kirsch.XAxis.TickLabels = {};
            app.kirsch.YAxis.TickLabels = {};
            
            app.removeVes.XAxis.TickLabels = {};
            app.removeVes.YAxis.TickLabels = {};
            
            app.lesionEdge.XAxis.TickLabels = {};
            app.lesionEdge.YAxis.TickLabels = {};
            
            app.extractVes.XAxis.TickLabels = {};
            app.extractVes.YAxis.TickLabels = {};
            
            app.status.Text = 'Ready';
            drawnow
        end

        % Button pushed function: ChooseimageButton
        function ChooseimageButtonPushed(app, event)
            startupFcn(app);
            
            app.status.FontColor = 'k';
            
            % Read image file
            [file, path] = uigetfile('*.*');
            try 
                im = imread(fullfile(path,file));
            catch
                msgbox("Error reading image");
                return;
            end
            
            % Resize image
            im = imresize(im, [605 NaN]);
            
            % Display image name
            app.imageName.Text = file;
            
            % Display origin image
            I = imshow(im, 'Parent', app.origin, ...
                'XData', [1 app.origin.Position(3)], ...
                'YData', [1 app.origin.Position(4)]);
            app.origin.XLim = [0 I.XData(2)];
            app.origin.YLim = [0 I.YData(2)];
            
            % Display 'working'
            app.status.Text = 'working...';
            drawnow
            
            % --------------------
            
            % Extract green channel
            green = double(im(:,:,2));
            % Display green channel image
            I = imshow(green, [], 'Parent', app.greenChan, ...
                'XData', [1 app.greenChan.Position(3)], ...
                'YData', [1 app.greenChan.Position(4)]);
            app.greenChan.XLim = [0 I.XData(2)];
            app.greenChan.YLim = [0 I.YData(2)];
            drawnow
            
            % --------------------
            
            % Get blood vessel and fundus mask
            fov_mask = get_mask(im);
            vessel = get_vessel(im,fov_mask);
            % Display vessel
            I = imshow(vessel, [], 'Parent', app.extractVes, ...
                'XData', [1 app.extractVes.Position(3)], ...
                'YData', [1 app.extractVes.Position(4)]);
            app.extractVes.XLim = [0 I.XData(2)];
            app.extractVes.YLim = [0 I.YData(2)];
            drawnow
            
            % ---------------------
            % Remove background
            medfilt_window = [25 25];
            background_est1 = medfilt2(green, medfilt_window);
            
            recon_mask = zeros(size(green), 'double');
            for i = 1:numel(green)
                if (green(i) < background_est1(i))
                    recon_mask(i) = background_est1(i);
                else
                    recon_mask(i) = green(i);
                end
            end
            
            % Reconstruct the background estimation
            background_est2 = imreconstruct(background_est1, recon_mask);
            
            % Remove background
            background_removed = green - background_est2;
            
            % Display background removed image
            I = imshow(background_removed, [], 'Parent', app.removeBg, ...
                'XData', [1 app.removeBg.Position(3)], ...
                'YData', [1 app.removeBg.Position(4)]);
            app.removeBg.XLim = [0 I.XData(2)];
            app.removeBg.YLim = [0 I.YData(2)];
            drawnow
            
            % -----------------------
            % Get lesion candidate
            threshold_value_bg = 9;
            lesion_candidate = background_removed > threshold_value_bg; % Convert to binary array
            lesion_candidate = bwareafilt(lesion_candidate, [10 Inf]); % Remove pepper noise
            
            % Display lesion candidate
            I = imshow(lesion_candidate, [], 'Parent', app.lesionCan, ...
                'XData', [1 app.lesionCan.Position(3)], ...
                'YData', [1 app.lesionCan.Position(4)]);
            app.lesionCan.XLim = [0 I.XData(2)];
            app.lesionCan.YLim = [0 I.YData(2)];
            drawnow
            
            % -----------------------
            
            % Kirsch's kernels
            k(:,:,1) = [ 5, 5, 5;-3, 0,-3;-3,-3,-3]/15;
            k(:,:,2) = [ 5, 5,-3; 5, 0,-3;-3,-3,-3]/15;
            k(:,:,3) = [ 5,-3,-3; 5, 0,-3; 5,-3,-3]/15;
            k(:,:,4) = [-3,-3,-3; 5, 0,-3; 5, 5,-3]/15;
            k(:,:,5) = [-3,-3,-3;-3, 0,-3; 5, 5, 5]/15;
            k(:,:,6) = [-3,-3,-3;-3, 0, 5;-3, 5, 5]/15;
            k(:,:,7) = [-3,-3, 5;-3, 0, 5;-3,-3, 5]/15;
            k(:,:,8) = [-3, 5, 5;-3, 0, 5;-3,-3,-3]/15;
            
            % Kirsch edge convolution results
            kirsch_conv_res = zeros([size(green),8], 'double');
            for i = 1:8 
                % Do convolution for 8 kernels
                kirsch_conv_res(:,:,i) = conv2(green,k(:,:,i),'same');
            end
            % Get the max for each pixel
            kirsch_max = max(kirsch_conv_res,[],3);
            kirsch_edge = kirsch_max;
            
            % Display kirsch applied image
            I = imshow(kirsch_edge, [], 'Parent', app.kirsch, ...
                'XData', [1 app.kirsch.Position(3)], ...
                'YData', [1 app.kirsch.Position(4)]);
            app.kirsch.XLim = [0 I.XData(2)];
            app.kirsch.YLim = [0 I.YData(2)];
            drawnow
            
            % ---------------------------
            
            % lesion edge candidate thresholding
            edge_threshold_value = 5;
            edge_candidate = kirsch_edge > edge_threshold_value;
                
            % Dilate vessel to remove bright regions alongside vessel
            vessel = imdilate(vessel,strel('disk',8));
            
            % Remove vessel from edge_candidate
            edge_ves_removed = edge_candidate - vessel;
            edge_ves_removed = edge_ves_removed > 0;

            % Display vessel removed image
            I = imshow(edge_ves_removed, [], 'Parent', app.removeVes, ...
                'XData', [1 app.removeVes.Position(3)], ...
                'YData', [1 app.removeVes.Position(4)]);
            app.removeVes.XLim = [0 I.XData(2)];
            app.removeVes.YLim = [0 I.YData(2)];
            drawnow
            
            
            % ---------------------------
            
            % Remove object smaller than 30 pixel
            edge_candidate = bwareafilt(edge_ves_removed, [30 Inf]);
            edge_candidate = bitand(edge_candidate,fov_mask);
            
            % Display 
            I = imshow(edge_candidate, [], 'Parent', app.lesionEdge, ...
                'XData', [1 app.lesionEdge.Position(3)], ...
                'YData', [1 app.lesionEdge.Position(4)]);
            app.lesionEdge.XLim = [0 I.XData(2)];
            app.lesionEdge.YLim = [0 I.YData(2)];
            drawnow
            
            % --------------------------
            % Reconstruct bright lesions
            lesion = imreconstruct(edge_candidate,lesion_candidate);
            
            % Display
            I = imshow(lesion, [], 'Parent', app.recon, ...
                'XData', [1 app.recon.Position(3)], ...
                'YData', [1 app.recon.Position(4)]);
            app.recon.XLim = [0 I.XData(2)];
            app.recon.YLim = [0 I.YData(2)];
            drawnow
            
            % ---------------------------
            % Display result
            
            % Display original image
            f1 = imshow(im, 'Parent', app.result, ...
                'XData', [1 app.result.Position(3)], ...
                'YData', [1 app.result.Position(4)]);
            app.result.XLim = [0 I.XData(2)];
            app.result.YLim = [0 I.YData(2)];
            % hold
            hold(app.result, 'on');
            
            % Display lesion
            f2 = imshow(lesion, 'Parent', app.result, ...
                'XData', [1 app.result.Position(3)], ...
                'YData', [1 app.result.Position(4)]);
            app.result.XLim = [0 I.XData(2)];
            app.result.YLim = [0 I.YData(2)];
            hold(app.result, 'off');
            % Set transparency
            set(f2, 'AlphaData', 0.5);
            
            
            % !! TEMP ! Count lesion pixels
            num_lesion = 0;
            for n = 1:numel(lesion)
                if (lesion(n) == 1)
                    num_lesion = num_lesion + 1;
                end
            end
            if num_lesion < 380
                conclusionText = 'No bright lesions detected.';
                app.status.FontColor = 'black';
            else
                conclusionText = 'Bright lesions detected!';
                app.status.FontColor = 'red';
            end    
            app.status.Text = conclusionText;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BrightLesionDetectionToolUIFigure = uifigure;
            app.BrightLesionDetectionToolUIFigure.Name = 'Bright Lesion Detection Tool';
            app.extractgreenchannelLabel = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.extractgreenchannelLabel.Text = 'extract green channel';
            app.removebackgroundLabel = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.removebackgroundLabel.Text = 'remove background';
            app.lesioncandidateLabel = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.lesioncandidateLabel.Text = 'lesion candidate';
            app.applykirschkernelLabel = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.applykirschkernelLabel.Text = 'apply kirsch kernel';
            app.removevesselLabel = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.removevesselLabel.Text = 'remove vessel';
            app.reconstructLabel = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.reconstructLabel.Text = 'reconstruct';
            app.lesionedgecandidateLabel = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.lesionedgecandidateLabel.Text = 'lesion edge candidate';
            app.resultLabel = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.resultLabel.Text = 'result';
            app.extractvesselLabel = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.extractvesselLabel.Text = 'extract vessel';
            app.ChooseimageButton = uibutton(app.BrightLesionDetectionToolUIFigure, 'push');
            app.ChooseimageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseimageButtonPushed, true);
            app.ChooseimageButton.Text = 'Choose image';
            app.origin = uiaxes(app.BrightLesionDetectionToolUIFigure);
            app.removeBg = uiaxes(app.BrightLesionDetectionToolUIFigure);
            app.lesionCan = uiaxes(app.BrightLesionDetectionToolUIFigure);
            app.recon = uiaxes(app.BrightLesionDetectionToolUIFigure);
            app.result = uiaxes(app.BrightLesionDetectionToolUIFigure);
            app.greenChan = uiaxes(app.BrightLesionDetectionToolUIFigure);
            app.kirsch = uiaxes(app.BrightLesionDetectionToolUIFigure);
            app.removeVes = uiaxes(app.BrightLesionDetectionToolUIFigure);
            app.lesionEdge = uiaxes(app.BrightLesionDetectionToolUIFigure);
            app.extractVes = uiaxes(app.BrightLesionDetectionToolUIFigure);
            app.status = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.status.Text = '0';
            app.imageName = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.imageName.Text = '';
            app.VLabel = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.VLabel.Text = 'V';
            app.Label = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.Label.Text = '-->';
            app.Label_2 = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.Label_2.Text = '/';
            app.Label_3 = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.Label_3.Text = '  _____^';
            app.Label_4 = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.Label_4.Text = '|';
            app.Label_5 = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.Label_5.Text = '-->';
            app.Label_6 = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.Label_6.Text = '-->';
            app.Label_7 = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.Label_7.Text = '-->';
            app.Label_8 = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.Label_8.Text = '-->';
            app.Label_9 = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.Label_9.Text = '-->';
            app.Label_10 = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.Label_10.Text = '-->';
            app.Label_11 = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.Label_11.Text = '^';
            app.Label_12 = uilabel(app.BrightLesionDetectionToolUIFigure);
            app.Label_12.Text = '-----------^';
        end
    end

    methods (Access = public)

        % Construct app
        function app = bright_lesion_detect

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BrightLesionDetectionToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BrightLesionDetectionToolUIFigure)
        end
    end
end